classdef FullBridgeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        alphaSliderLabel      matlab.ui.control.Label
        alphaSlider           matlab.ui.control.Slider
        TitleLabel            matlab.ui.control.Label
        THDLabel              matlab.ui.control.Label
        SimularButton         matlab.ui.control.Button
        TericoEditFieldLabel  matlab.ui.control.Label
        THDTeoEditField       matlab.ui.control.NumericEditField
        PSIMEditFieldLabel    matlab.ui.control.Label
        THDPSIMEditField      matlab.ui.control.NumericEditField
        ErroEditField_2Label  matlab.ui.control.Label
        ErroEditField         matlab.ui.control.NumericEditField
        TabGroup              matlab.ui.container.TabGroup
        FromasdeOndaTab       matlab.ui.container.Tab
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes1               matlab.ui.control.UIAxes
        UIAxes3               matlab.ui.control.UIAxes
        EspectroTab           matlab.ui.container.Tab
        UIAxes4               matlab.ui.control.UIAxes
        alphaEditField        matlab.ui.control.NumericEditField
        MsgLabel              matlab.ui.control.Label
        t1sdt1E5Label         matlab.ui.control.Label
    end


    methods (Access = private)
    
        function alphaChanged(app, alpha)
            
            app.MsgLabel.Text = "Clique em Simular";
            app.MsgLabel.Visible = "on";
            
            app.alphaEditField.Value = alpha;
            mdfd_sq = plotWaveForms(app, alpha);
            
            r = thd(100*mdfd_sq, 2*pi*60/(pi/50), 20);
            app.THDTeoEditField.Value = r;
            
            plotSpectrum(app, 100*mdfd_sq(1:300), 2*pi*60/(pi/50));
            
        end
    
        function mdfd_sq = plotWaveForms(app, alpha)
            
            wt = 0:(pi/50):(60*pi);
            sq1 = 0.5*square(wt + (pi/180)*alpha);
            sq2 = 0.5*square(wt + pi - (pi/180)*alpha);
            
            mdfd_sq = sq1 - sq2;
            
            axes_ = {app.UIAxes1, app.UIAxes2, app.UIAxes3};
            waves = {sq1, sq2, mdfd_sq}; colors = {'b', 'g', 'r'};
            names = {'Vo_1', 'Vo_2', 'Vo'};
            yticks1 = [-0.5, 0, 0.5]; yticklabels1 = {'-50', '0', '+50'};
            yticks2 = [-1, 0, 1]; yticklabels2 = {'-100', '0', '+100'};
            yticks_ = {yticks1, yticks1, yticks2};
            yticklabels_ = {yticklabels1, yticklabels1, yticklabels2};
            
            for i = 1:3
                
                plot(axes_{i}, wt, waves{i}, colors{i}, 'LineWidth', 1.1);
                grid(axes_{i}, 'on');
                xlim(axes_{i}, [0, 4*pi]);
                xticks(axes_{i}, [0, pi, 2*pi, 3*pi, 4*pi]);
                xticklabels(axes_{i}, {'0', '180', '360', '540', '720'});
                yticks(axes_{i}, yticks_{i});
                yticklabels(axes_{i}, yticklabels_{i});
                xlabel(axes_{i}, '\omega t(º)');
                ylabel(axes_{i}, names{i});
                title(axes_{i}, '');
                
            end
            
        end
    
        function plotSpectrum(app, mdfd_sq, fs)
            
            n = length(mdfd_sq);
            
            Y = fft(mdfd_sq, n);
            
            Pyy = Y.*conj(Y) / n;
            f = fs/n * (0:(ceil(n/2)+1));
            
            cla(app.UIAxes4, 'reset');
            stem(app.UIAxes4, f, Pyy(1:length(f)), '^', 'LineWidth', 1.1, 'MarkerFaceColor', 'auto');
            grid(app.UIAxes4, 'on');
            xlim(app.UIAxes4, [0, 540]);
            xticks(app.UIAxes4, [0, 60, 180, 300, 420, 540]);
            xticklabels(app.UIAxes4, {'0', '60', '180', '300', '420', '540'});
            xlabel(app.UIAxes4, 'Frequência (Hz)');
            ylabel(app.UIAxes4, 'Potência (W - 1\Omega)'); yticklabels(app.UIAxes4, '');
            title(app.UIAxes4, 'Espectro de Potência de Vo');
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            alpha = app.alphaSlider.Value;
            alphaChanged(app, alpha);
            
        end

        % Value changed function: alphaSlider
        function alphaSliderValueChanged(app, event)
            alpha = app.alphaSlider.Value;
            
            alphaChanged(app, alpha);
        end

        % Value changing function: alphaSlider
        function alphaSliderValueChanging(app, event)
            alpha = event.Value;
            
            alphaChanged(app, alpha);
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            
            app.MsgLabel.Text = "Simulando";
            app.MsgLabel.Visible = "on";
            
            alpha = app.alphaSlider.Value;
            assignin('base', 'alpha', alpha);
            
            evalin('base', 'simFromAppScript2');
            
            Time = evalin('base', 'Time');
            Vq1 = evalin('base', 'Vq1');
            Vq2 = evalin('base', 'Vq2');
            Vo = evalin('base', 'Vo');
            
            axes_ = {app.UIAxes1, app.UIAxes2, app.UIAxes3};
            waves = {Vq1, Vq2, Vo}; colors = {'b', 'g', 'r'};
            names = {'V_{q1}(volts)', 'V_{q2}(volts)', 'V_{o}(volts)'};
            
            for i = 1:3
                
                cla(axes_{i}, 'reset');
                plot(axes_{i}, Time, waves{i}, colors{i}, 'LineWidth', 1.1);
                xlim(axes_{i}, [Time(1), Time(end)]);
                grid(axes_{i}, 'on');
                xlabel(axes_{i}, 'Tempo (s)');
                ylabel(axes_{i}, names{i});
                
            end
            
            title(app.UIAxes1, 'Resultados (0.95 < t < 1 s)');
            
            plotSpectrum(app, Vo, 1/1e-5);
            
            r = evalin('base', 'r');
            app.THDPSIMEditField.Value = r;
            
            r_teo = app.THDTeoEditField.Value;
            erro = abs(r - r_teo);
            app.ErroEditField.Value = erro;
            
            app.MsgLabel.Visible = 'off';
            
        end

        % Value changed function: alphaEditField
        function alphaEditFieldValueChanged(app, event)
            alpha = app.alphaEditField.Value;
            
            app.alphaSlider.Value = alpha;
            alphaChanged(app, alpha);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.alphaSliderLabel = uilabel(app.UIFigure);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.UIFigure);
            app.alphaSlider.Limits = [0 89];
            app.alphaSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 89];
            app.alphaSlider.ValueChangedFcn = createCallbackFcn(app, @alphaSliderValueChanged, true);
            app.alphaSlider.ValueChangingFcn = createCallbackFcn(app, @alphaSliderValueChanging, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Onda Quadrada Modificada';
            app.THDLabel = uilabel(app.UIFigure);
            app.THDLabel.Text = 'THD (dB)';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.TericoEditFieldLabel = uilabel(app.UIFigure);
            app.TericoEditFieldLabel.Text = 'Teórico';
            app.THDTeoEditField = uieditfield(app.UIFigure, 'numeric');
            app.THDTeoEditField.Editable = 'off';
            app.PSIMEditFieldLabel = uilabel(app.UIFigure);
            app.PSIMEditFieldLabel.Text = 'PSIM';
            app.THDPSIMEditField = uieditfield(app.UIFigure, 'numeric');
            app.THDPSIMEditField.Editable = 'off';
            app.ErroEditField_2Label = uilabel(app.UIFigure);
            app.ErroEditField_2Label.Text = 'Erro';
            app.ErroEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErroEditField.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FromasdeOndaTab = uitab(app.TabGroup);
            app.FromasdeOndaTab.Title = 'Fromas de Onda';
            app.UIAxes2 = uiaxes(app.FromasdeOndaTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes1 = uiaxes(app.FromasdeOndaTab);
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            app.UIAxes3 = uiaxes(app.FromasdeOndaTab);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.EspectroTab = uitab(app.TabGroup);
            app.EspectroTab.Title = 'Espectro';
            app.UIAxes4 = uiaxes(app.EspectroTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged, true);
            app.MsgLabel = uilabel(app.UIFigure);
            app.MsgLabel.Visible = 'off';
            app.MsgLabel.Text = 'Clique em Simular';
            app.t1sdt1E5Label = uilabel(app.UIFigure);
            app.t1sdt1E5Label.Text = 't = 1s; dt = 1E-5';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FullBridgeApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end