classdef PowerFluxApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FluxodePotnciaLabel    matlab.ui.control.Label
        L00117HLabel           matlab.ui.control.Label
        PgEditField            matlab.ui.control.NumericEditField
        PgSliderLabel          matlab.ui.control.Label
        PgSlider               matlab.ui.control.Slider
        QgEditField            matlab.ui.control.NumericEditField
        QgSliderLabel          matlab.ui.control.Label
        QgSlider               matlab.ui.control.Slider
        VgLabel                matlab.ui.control.Label
        SimularButton          matlab.ui.control.Button
        VgVrmsEditFieldLabel   matlab.ui.control.Label
        VgRmsEditField         matlab.ui.control.NumericEditField
        phaseVgEditFieldLabel  matlab.ui.control.Label
        VgAngEditField         matlab.ui.control.NumericEditField
        ErroEditFieldLabel     matlab.ui.control.Label
        ErroEditField          matlab.ui.control.NumericEditField
        PgErrorLabel           matlab.ui.control.Label
        PgEditField_2Label     matlab.ui.control.Label
        PgPSIMEditField        matlab.ui.control.NumericEditField
        QgErrorLabel           matlab.ui.control.Label
        QgEditField_2Label     matlab.ui.control.Label
        QgPSIMEditField        matlab.ui.control.NumericEditField
        ResultadoPSIMLabel     matlab.ui.control.Label
        tf1sdt1E5Label         matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        MsgLabel               matlab.ui.control.Label
    end


    methods (Access = private)
    
        function TeoPowerChanged(app, Pg, Qg)
            
            app.PgSlider.Value = Pg;
            app.QgSlider.Value = Qg;
            
            app.PgEditField.Value = Pg;
            app.QgEditField.Value = Qg;
            
            app.MsgLabel.Text = 'Clique em Simular';
            app.MsgLabel.Visible = 'on';
            
            if(Pg == 0 && Qg == 0)
                app.SimularButton.Enable = 'off';
            else
                app.SimularButton.Enable = 'on';
            end
            
            [Vg_rms, Vg_ang] = calculateVg(app, Pg, Qg);
            
            plotTeoVg(app, Vg_rms, Vg_ang);
            
            app.VgRmsEditField.Value = Vg_rms;
            app.VgAngEditField.Value = Vg_ang;
            
        end
    
        function [Vg_rms, Vg_ang] = calculateVg(app, Pg, Qg)
            
            Vr = 220;
            Xl = Vr/50;
            
            Vg_rms = real(sqrt((2*Qg*Xl + Vr^2 + sqrt((2*Qg*Xl + Vr^2).^2 - ...
                    4*(Pg.^2 + Qg.^2)*Xl^2))/2));

            Vg_ang = real((180/pi)*asin((Pg*Xl)./(Vg_rms * Vr)));
            
        end
    
        function plotTeoVg(app, Vg_rms, Vg_ang)
            
            wt = 0:(pi/50):4*pi;
            Vg = Vg_rms*sqrt(2)*sin(wt + (pi/180)*Vg_ang);
            
            Vr = 220*sqrt(2)*sin(wt);
            
            cla(app.UIAxes, 'reset');
            plot(app.UIAxes, wt, Vr, wt, Vg, 'LineWidth', 1.1);
            xlim(app.UIAxes, [0, 4*pi])
            xticks(app.UIAxes, [0, pi, 2*pi, 3*pi, 4*pi]);
            xticklabels(app.UIAxes, {'\0', '180', '320', '540', '720'});
            xlabel(app.UIAxes, '\omega t(º)');
            ylabel(app.UIAxes, 'V (volts)');
            legend(app.UIAxes, {'V_r', 'Vg'});
            title(app.UIAxes, 'Tensões das Fontes');
            grid(app.UIAxes, 'on');
            
        end
    
        function plotPSIMResults(app, Time, Vr, Vg, Pg_PSIMv, Qg_PSIMv)
            
            cla(app.UIAxes, 'reset');
            yyaxis(app.UIAxes, 'left');
            plot(app.UIAxes, Time, Vr, Time, Vg, 'LineWidth', 1.1);
            ylabel(app.UIAxes, 'Tensão (volts)');
            yyaxis(app.UIAxes, 'right');
            plot(app.UIAxes, Time, Pg_PSIMv, Time, Qg_PSIMv);
            ylabel(app.UIAxes, 'P(W); Q(var)');
            xlim(app.UIAxes, [min(Time), max(Time)]);
            xlabel(app.UIAxes, 'Tempo (s)');
            legend(app.UIAxes, {'V_r', 'V_g', 'P_g', 'Q_g'}, 'Interpreter', 'tex', 'Location', 'East');
            title(app.UIAxes, 'Resultados do PSIM');
            grid(app.UIAxes, 'on');
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            Pg = app.PgSlider.Value;
            Qg = app.QgSlider.Value;
            
            TeoPowerChanged(app, Pg, Qg);
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            
            app.MsgLabel.Text = 'Simulando';
            app.MsgLabel.Visible = 'on';
            
            Pg = app.PgEditField.Value;
            Qg = app.QgEditField.Value;
            Vg_rms = app.VgRmsEditField.Value;
            Vg_ang = app.VgAngEditField.Value;
            
            assignin('base', 'Pg', Pg);
            assignin('base', 'Qg', Qg);
            assignin('base', 'Vg_rms', Vg_rms);
            assignin('base', 'Vg_ang', Vg_ang);
            assignin('base', 'L', 0.0117);
                        
            evalin('base', 'simFromAppScript');
            
            Pg_PSIMv = evalin('base', 'Pg_PSIMv');
            Qg_PSIMv = evalin('base', 'Qg_PSIMv');
            
            Pg_PSIM = evalin('base', 'Pg_PSIM');
            Qg_PSIM = evalin('base', 'Qg_PSIM');
            
            Time = evalin('base', 'Time');
            Vg = evalin('base', 'Vg');
            Vr = evalin('base', 'Vr');
            
            PgError = evalin('base', 'PgError');
            QgError = evalin('base', 'QgError');
            erro = evalin('base', 'erro');
            
            plotPSIMResults(app, Time, Vr, Vg, Pg_PSIMv, Qg_PSIMv);
            
            app.PgPSIMEditField.Value = Pg_PSIM;
            app.QgPSIMEditField.Value = Qg_PSIM;
            app.ErroEditField.Value = erro;
            
            app.PgErrorLabel.Text = sprintf('+1.2%f', PgError);
            app.QgErrorLabel.Text = sprintf('+1.2%f', QgError);
            
            app.MsgLabel.Visible = 'off';
            
        end

        % Value changed function: PgSlider, QgSlider
        function SliderValueChanged(app, event)
            Pg = app.PgSlider.Value;
            Qg = app.QgSlider.Value;
            
            TeoPowerChanged(app, Pg, Qg);
        end

        % Value changing function: PgSlider
        function PgSliderValueChanging(app, event)
            Pg = event.Value;
            Qg = app.QgSlider.Value;
            
            TeoPowerChanged(app, Pg, Qg);
        end

        % Value changing function: QgSlider
        function QgSliderValueChanging(app, event)
            Qg = event.Value;
            Pg = app.PgSlider.Value;
            
            TeoPowerChanged(app, Pg, Qg);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FluxodePotnciaLabel = uilabel(app.UIFigure);
            app.FluxodePotnciaLabel.Text = 'Fluxo de Potência';
            app.L00117HLabel = uilabel(app.UIFigure);
            app.L00117HLabel.Text = 'L = 0.0117 H';
            app.PgEditField = uieditfield(app.UIFigure, 'numeric');
            app.PgSliderLabel = uilabel(app.UIFigure);
            app.PgSliderLabel.Text = 'Pg';
            app.PgSlider = uislider(app.UIFigure);
            app.PgSlider.Limits = [0 1000];
            app.PgSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.PgSlider.ValueChangingFcn = createCallbackFcn(app, @PgSliderValueChanging, true);
            app.QgEditField = uieditfield(app.UIFigure, 'numeric');
            app.QgSliderLabel = uilabel(app.UIFigure);
            app.QgSliderLabel.Text = 'Qg';
            app.QgSlider = uislider(app.UIFigure);
            app.QgSlider.Limits = [0 1000];
            app.QgSlider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.QgSlider.ValueChangingFcn = createCallbackFcn(app, @QgSliderValueChanging, true);
            app.VgLabel = uilabel(app.UIFigure);
            app.VgLabel.Text = 'Vg';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.VgVrmsEditFieldLabel = uilabel(app.UIFigure);
            app.VgVrmsEditFieldLabel.Text = 'Vg (Vrms)';
            app.VgRmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VgRmsEditField.Editable = 'off';
            app.phaseVgEditFieldLabel = uilabel(app.UIFigure);
            app.phaseVgEditFieldLabel.Text = 'phase(Vg) (º)';
            app.VgAngEditField = uieditfield(app.UIFigure, 'numeric');
            app.VgAngEditField.Editable = 'off';
            app.ErroEditFieldLabel = uilabel(app.UIFigure);
            app.ErroEditFieldLabel.Text = 'Erro';
            app.ErroEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErroEditField.ValueDisplayFormat = '%0.2E';
            app.ErroEditField.Editable = 'off';
            app.PgErrorLabel = uilabel(app.UIFigure);
            app.PgErrorLabel.Text = 'PgError';
            app.PgEditField_2Label = uilabel(app.UIFigure);
            app.PgEditField_2Label.Text = 'Pg';
            app.PgPSIMEditField = uieditfield(app.UIFigure, 'numeric');
            app.PgPSIMEditField.Editable = 'off';
            app.QgErrorLabel = uilabel(app.UIFigure);
            app.QgErrorLabel.Text = 'QgError';
            app.QgEditField_2Label = uilabel(app.UIFigure);
            app.QgEditField_2Label.Text = 'Qg';
            app.QgPSIMEditField = uieditfield(app.UIFigure, 'numeric');
            app.QgPSIMEditField.Editable = 'off';
            app.ResultadoPSIMLabel = uilabel(app.UIFigure);
            app.ResultadoPSIMLabel.Text = 'Resultado (PSIM)';
            app.tf1sdt1E5Label = uilabel(app.UIFigure);
            app.tf1sdt1E5Label.Text = 'tf = 1s;   dt = 1E-5';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MsgLabel = uilabel(app.UIFigure);
            app.MsgLabel.Text = 'Clique em Simular';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PowerFluxApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end